classdef HarvestMate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Label                    matlab.ui.control.Label
        Reset                    matlab.ui.control.Button
        ESTOPLabel               matlab.ui.control.Label
        ManualModeLamp           matlab.ui.control.Lamp
        ManualModeLampLabel      matlab.ui.control.Label
        Joint7Slider             matlab.ui.control.Slider
        Joint7SliderLabel        matlab.ui.control.Label
        Joint6Slider             matlab.ui.control.Slider
        Joint6SliderLabel        matlab.ui.control.Label
        Joint5Slider             matlab.ui.control.Slider
        Joint5SliderLabel        matlab.ui.control.Label
        Joint4Slider             matlab.ui.control.Slider
        Joint4SliderLabel        matlab.ui.control.Label
        Joint3Slider             matlab.ui.control.Slider
        Joint3SliderLabel        matlab.ui.control.Label
        Joint2Slider             matlab.ui.control.Slider
        Joint2SliderLabel        matlab.ui.control.Label
        Joint1Slider             matlab.ui.control.Slider
        Joint1SliderLabel        matlab.ui.control.Label
        RobotSwitch              matlab.ui.control.RockerSwitch
        ManualModeControlsLabel  matlab.ui.control.Label
        ManualSwitch             matlab.ui.control.Switch
        Lamp                     matlab.ui.control.Lamp
        GripperSlider            matlab.ui.control.Slider
        GripperSliderLabel       matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        STARTButton_2            matlab.ui.control.Button
        ESTOP                    matlab.ui.control.StateButton
        RobotActionButtonGroup   matlab.ui.container.ButtonGroup
        DualActionButton         matlab.ui.control.ToggleButton
        SortButton               matlab.ui.control.ToggleButton
        HarvestButton            matlab.ui.control.ToggleButton
        Button                   matlab.ui.control.ToggleButton
        ProduceButtonGroup       matlab.ui.container.ButtonGroup
        MixedButton              matlab.ui.control.ToggleButton
        PotatoesButton           matlab.ui.control.ToggleButton
        Button_2                 matlab.ui.control.ToggleButton
        TomatoesButton           matlab.ui.control.ToggleButton
    end

    
    properties (Access = public)
        ClearMessageTimer % Timer to clear message after 3 seconds

        % Robot Related
        harvesterBot;
        sortingBot;
        rightGripper;
        leftGripper;
        rightHarvester;
        leftHarvester;
        rightSorter;
        leftSorter;
        steps;
        gripperLength;
        gripperHeight;
        pointDown;
        pointForwards;
        pointBackwards;
        selectedRobot;

        % Produce Related
        tomatoObject;
        tomatoVertices;
        potatoObject;
        potatoVertices;
        producePositions; % Array of positions for each produce item
        produceTags;      % Tags for identifying produce types
        goodTomatoes;
        badTomatoes;
        goodPotatoes;
        badPotatoes;
        % tomatoIndex;
        % potatoIndex;
    
        % Box locations for sorting
        unsortedBoxMix;
        unsortedBox;
        goodBox;
        badBox;
        unsortedBoxEEPos;
        goodBoxEEPose;
        badBoxEEPose;

    end
    
    methods (Access = private)

        function gripperAnimation(app,angle)
            % Produce link vector.
                leftQ = [deg2rad(-angle),deg2rad(angle),0];
                rightQ = [deg2rad(angle),deg2rad(-angle),0];
            % Animate gripper depending on robot.
            if strcmp(app.RobotSwitch.Value, 'Sorter')
                % animation for LinearUR3 gripper.
                app.leftSorter.model.animate(leftQ);
                app.rightSorter.model.animate(rightQ);

            elseif strcmp(app.RobotSwitch.Value, 'Harvester')
                % animation for Panda gripper.
                app.leftHarvester.model.animate(leftQ);
                app.rightHarvester.model.animate(rightQ);
            end
        end
        
        function UpdateJointPosition(app, jointIndex, sliderValue)
            % Converts slider value to radians and updates the selected robot's joint
            % configuration for the specified joint index.
            
            if isempty(app.selectedRobot)
                disp('No robot selected for manual control.');
                return;
            end

            % Convert the slider value (assumed to be in degrees) to radians
            jointAngle = rad2deg(sliderValue);
            
            % Get the current joint configuration and update the specified joint
            qCurrent = app.selectedRobot.model.getpos();
            qCurrent(jointIndex) = jointAngle;
            
            % Update the robot's joint configuration
            app.selectedRobot.model.animate(qCurrent);

            if strcmp(app.RobotSwitch.Value, 'Sorter')
                % Gripper base transform for LinearUR3.
                pos1 = app.selectedRobot.model.fkineUTS(app.selectedRobot.model.getpos())*transl(0,-0.0127,0.0612)*troty(-pi/2);%z0.0612
                pos2 = app.selectedRobot.model.fkineUTS(app.selectedRobot.model.getpos())*transl(0,0.0127,0.0612)*troty(-pi/2);%z0.0612

                app.leftSorter.model.base = pos2; 
                app.rightSorter.model.base = pos1; 

                app.leftSorter.model.animate(app.leftSorter.model.getpos());
                app.rightSorter.model.animate(app.rightSorter.model.getpos());

            elseif strcmp(app.RobotSwitch.Value, 'Harvester')
                % Gripper base transform for Panda.
                pos1 = app.selectedRobot.model.fkineUTS(app.selectedRobot.model.getpos())*transl(0,-0.0127,0.05)*troty(-pi/2);%z0.0612
                pos2 = app.selectedRobot.model.fkineUTS(app.selectedRobot.model.getpos())*transl(0,0.0127,0.05)*troty(-pi/2);%z0.0612

                app.leftHarvester.model.base = pos2; 
                app.rightHarvester.model.base = pos1; 

                app.leftHarvester.model.animate(app.leftHarvester.model.getpos());
                app.rightHarvester.model.animate(app.rightHarvester.model.getpos());
            end
        end
        
        function displayMessage(app, message)
            app.Label.Text = message;
            % Restart the timer to count down from 3 seconds
            stop(app.ClearMessageTimer); % Stop the timer if it’s already running
            start(app.ClearMessageTimer); % Restart the timer
        end

        function clearLabel(app)
            app.Label.Text = '';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ClearMessageTimer = timer('StartDelay', 2, 'TimerFcn', @(~,~) clearLabel(app), 'ExecutionMode', 'singleShot');
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Button pushed function: STARTButton_2
        function STARTButton_2Pushed(app, event)
            % clear;
            app.Lamp.Color = 'green'; % Turn the lamp green to indicate "started"
            clf;
            pause(0.1);
            clc;
            % set(0,'DefaultFigureWindowStyle','docked');
            view(3)
            hold on
            axis([-2.5, 2.5, -2.5, 2.5, 0.01, 2]);            
            disp('Initialising Environment...');
            displayMessage(app, 'Initialising Environment...');
            
            % Load the environment
            LoadEnvironment();

            % locations for fruit to be placed when dropped
            [app.unsortedBox,app.goodBox,app.badBox] = ProduceClass.BoxLocations();

            app.steps = 50;
            app.gripperLength = 0.24;
            app.gripperHeight = 0.025;

            % Orientation for the end-effector.
            app.pointForwards = trotx(270, 'deg');
            app.pointDown = trotx(180,'deg');
            app.pointBackwards = trotx(90, 'deg');

            % unsorted box position for EE
            app.unsortedBoxEEPos = [0, 0, 0.6]; % box to drop produce
            
            % Sorted box positions for EE
            app.goodBoxEEPose = [0.6, -1.75, 0.6];
            app.badBoxEEPose = [1.1, -1, 0.6];


            [app.harvesterBot, app.sortingBot, app.rightHarvester, app.leftHarvester, app.rightSorter, app.leftSorter] = LoadRobots();

            app.selectedRobot = app.harvesterBot;

            app.ProduceButtonGroup.Enable = 'on';
            app.RobotActionButtonGroup.Enable = 'on';
            app.ESTOP.Enable = 'on';
            app.ManualSwitch.Enable = 'on';
            app.Reset.Enable = "on";


            disp('Environment Loaded!');
            displayMessage(app, 'Enirvonment Loaded!');
            
        end

        % Selection changed function: ProduceButtonGroup
        function ProduceButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProduceButtonGroup.SelectedObject.Text;

            switch selectedButton
                case 'Tomatoes'
                    [app.producePositions, app.tomatoObject, app.tomatoVertices, app.produceTags] = ProduceClass.GenerateTomatoes();
                    disp('Generated Tomatoes.');
                    displayMessage(app, 'Tomatoes generated successfully.');
                case 'Potatoes'
                    [app.producePositions, app.potatoObject, app.potatoVertices, app.produceTags] = ProduceClass.GeneratePotatoes();
                    disp('Generated Potatoes.');
                    displayMessage(app, 'Potatoes generated successfully.');
                case 'Mixed'
                    [app.producePositions, app.potatoObject, app.potatoVertices, app.tomatoObject, app.tomatoVertices, app.produceTags] = ProduceClass.GenerateMix();
                    disp('Generated Mixed Produce.');
                    displayMessage(app, 'Tomatoes & Potatoes generated successfully.');
            end
        end

        % Callback function: ProduceButtonGroup, RobotActionButtonGroup
        function ProduceButtonGroupButtonDown(app, event)
            selectedAction = app.RobotActionButtonGroup.SelectedObject.Text;
            switch selectedAction
                case 'Harvest'
                    disp('Harvester Started.');
                    displayMessage(app, 'Harvesting Started');
                    StartHarvesting(app);
                case 'Sort'
                    disp('Sorter Started.');
                    displayMessage(app, 'Sorting Started');
                    StartSorting(app);
                case 'Dual Action'
                    disp('Dual Action Started.');
                    displayMessage(app, 'Harvest and Sorting Started');
                    DualAction(app);
            end
        end

        % Value changed function: ESTOP
        function ESTOPValueChanged(app, event)
            value = app.ESTOP.Value;
            RobotClass.ToggleEStop();
            if value
                app.Lamp.Color = 'red';
                displayMessage(app, 'ESTOP ENGAGED!!!');
                app.STARTButton_2.Enable = 'off';
                app.ManualModeLamp.Color = 'white';
                app.ManualSwitch.Enable = 'off';
                app.RobotSwitch.Enable = 'off';
                app.GripperSlider.Enable = 'off';
                app.Joint1Slider.Enable = 'off';
                app.Joint2Slider.Enable = 'off';
                app.Joint3Slider.Enable = 'off';
                app.Joint4Slider.Enable = 'off';
                app.Joint5Slider.Enable = 'off';
                app.Joint6Slider.Enable = 'off';
                app.Joint7Slider.Enable = 'off';
                app.ProduceButtonGroup.Enable = 'off';
                app.RobotActionButtonGroup.Enable = 'off';
            else
                app.Lamp.Color = 'green';
                displayMessage(app, 'ESTOP DISENGAGED!!!');
                app.STARTButton_2.Enable = 'on';
                app.ProduceButtonGroup.Enable = 'on';
                app.RobotActionButtonGroup.Enable = 'on';
                app.ManualSwitch.Enable = 'on';

            end
        end

        % Value changed function: ManualSwitch
        function ManualSwitchValueChanged(app, event)
            value = app.ManualSwitch.Value;
            
            % Toggle the components based on the manual switch state
            if strcmp(value, 'On')
                app.ManualModeLamp.Color = 'blue';
                app.RobotSwitch.Enable = 'on';
                app.GripperSlider.Enable = 'on';
                app.Joint1Slider.Enable = 'on';
                app.Joint2Slider.Enable = 'on';
                app.Joint3Slider.Enable = 'on';
                app.Joint4Slider.Enable = 'on';
                app.Joint5Slider.Enable = 'on';
                app.Joint6Slider.Enable = 'on';
                app.Joint7Slider.Enable = 'on';
                app.ProduceButtonGroup.Enable = 'off';
                app.RobotActionButtonGroup.Enable = 'off';
                disp('Manual mode enabled');
                displayMessage(app, 'Manual Mode On');
            else
                app.ManualModeLamp.Color = 'white';
                app.RobotSwitch.Enable = 'off';
                app.GripperSlider.Enable = 'off';
                app.Joint1Slider.Enable = 'off';
                app.Joint2Slider.Enable = 'off';
                app.Joint3Slider.Enable = 'off';
                app.Joint4Slider.Enable = 'off';
                app.Joint5Slider.Enable = 'off';
                app.Joint6Slider.Enable = 'off';
                app.Joint7Slider.Enable = 'off';
                app.ProduceButtonGroup.Enable = 'on';
                app.RobotActionButtonGroup.Enable = 'on';
                disp('Manual mode disabled');
                displayMessage(app, 'Manual Mode Off');
            end
        
        end

        % Value changed function: RobotSwitch
        function RobotSwitchValueChanged(app, event)
            value = app.RobotSwitch.Value;
        
            if strcmp(value, 'Harvester')
                app.selectedRobot = app.harvesterBot;
                q = app.selectedRobot.model.getpos();
               
                % Set limits for Panda robot
                app.Joint1Slider.Limits = [-pi, pi]*pi/180;
                app.Joint2Slider.Limits = [-2.26893, 2.07694];
                app.Joint3Slider.Limits = [-pi, pi];
                app.Joint4Slider.Limits = [-2.1293, 2.0944];
                app.Joint5Slider.Limits = [-pi, pi];
                app.Joint6Slider.Limits = [-pi, pi];
                app.Joint7Slider.Limits = [-pi, pi];
                
                % Initialize each slider to the current joint position
                app.Joint1Slider.Value = min(max(q(1, 1), app.Joint1Slider.Limits(1)), app.Joint1Slider.Limits(2));
                app.Joint2Slider.Value = min(max(q(1, 2), app.Joint2Slider.Limits(1)), app.Joint2Slider.Limits(2));
                app.Joint3Slider.Value = min(max(q(1, 3), app.Joint3Slider.Limits(1)), app.Joint3Slider.Limits(2));
                app.Joint4Slider.Value = min(max(q(1, 5), app.Joint4Slider.Limits(1)), app.Joint4Slider.Limits(2));
                app.Joint5Slider.Value = min(max(q(1, 7), app.Joint5Slider.Limits(1)), app.Joint5Slider.Limits(2));
                app.Joint6Slider.Value = min(max(q(1, 9), app.Joint6Slider.Limits(1)), app.Joint6Slider.Limits(2));
                app.Joint7Slider.Value = min(max(q(1, 11), app.Joint7Slider.Limits(1)), app.Joint7Slider.Limits(2));
        
            
            elseif strcmp(value, 'Sorter')
                app.selectedRobot = app.sortingBot;
                q = app.selectedRobot.model.getpos();
        
                % Set limits and initial values for the UR3e
                app.Joint1Slider.Limits = [-0.8, -0.01]; % Linear rail (prismatic joint)
                app.Joint2Slider.Limits = [-2*pi, 2*pi];
                app.Joint3Slider.Limits = [-pi/2, pi/2];
                app.Joint4Slider.Limits = [-170*pi/180, 170*pi/180];
                app.Joint5Slider.Limits = [-2*pi, 2*pi];
                app.Joint6Slider.Limits = [-2*pi, 2*pi];
                app.Joint7Slider.Limits = [-2*pi, 2*pi];
                
                % Initialize each slider to the current joint position
                app.Joint1Slider.Value = min(max(q(1, 1), app.Joint1Slider.Limits(1)), app.Joint1Slider.Limits(2));
                app.Joint2Slider.Value = min(max(q(1, 2), app.Joint2Slider.Limits(1)), app.Joint2Slider.Limits(2));
                app.Joint3Slider.Value = min(max(q(1, 3), app.Joint3Slider.Limits(1)), app.Joint3Slider.Limits(2));
                app.Joint4Slider.Value = min(max(q(1, 4), app.Joint4Slider.Limits(1)), app.Joint4Slider.Limits(2));
                app.Joint5Slider.Value = min(max(q(1, 5), app.Joint5Slider.Limits(1)), app.Joint5Slider.Limits(2));
                app.Joint6Slider.Value = min(max(q(1, 6), app.Joint6Slider.Limits(1)), app.Joint6Slider.Limits(2));
                app.Joint7Slider.Value = min(max(q(1, 7), app.Joint7Slider.Limits(1)), app.Joint7Slider.Limits(2));
            end
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            sliderValue = app.Joint1Slider.Value;
            UpdateJointPosition(app, 1, sliderValue);
        end

        % Value changed function: GripperSlider
        function GripperSliderValueChanged(app, event)
            gripperAnimation(app,event.Value)
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            sliderValue = app.Joint1Slider.Value;
            UpdateJointPosition(app, 2, sliderValue);
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            sliderValue = app.Joint1Slider.Value;
            UpdateJointPosition(app, 3, sliderValue);
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            sliderValue = app.Joint1Slider.Value;
            UpdateJointPosition(app, 4, sliderValue);
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
            sliderValue = app.Joint1Slider.Value;
            UpdateJointPosition(app, 5, sliderValue);
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
            sliderValue = app.Joint1Slider.Value;
            UpdateJointPosition(app, 6, sliderValue);
        end

        % Value changed function: Joint7Slider
        function Joint7SliderValueChanged(app, event)
            sliderValue = app.Joint1Slider.Value;
            UpdateJointPosition(app, 7, sliderValue);
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            % Set all joint angles to zero
            for i = 1:7
                UpdateJointPosition(app,i,0);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.ProduceButtonGroup = uibuttongroup(app.UIFigure);
            app.ProduceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProduceButtonGroupSelectionChanged, true);
            app.ProduceButtonGroup.Enable = 'off';
            app.ProduceButtonGroup.Title = 'Produce';
            app.ProduceButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ProduceButtonGroupButtonDown, true);
            app.TomatoesButton = uitogglebutton(app.ProduceButtonGroup);
            app.TomatoesButton.Icon = fullfile(pathToMLAPP, 'Icons', 'tomato.png');
            app.TomatoesButton.Text = 'Tomatoes';
            app.Button_2 = uitogglebutton(app.ProduceButtonGroup);
            app.Button_2.Text = '';
            app.Button_2.Value = true;
            app.PotatoesButton = uitogglebutton(app.ProduceButtonGroup);
            app.PotatoesButton.Icon = fullfile(pathToMLAPP, 'Icons', 'potato.png');
            app.PotatoesButton.Text = 'Potatoes';
            app.MixedButton = uitogglebutton(app.ProduceButtonGroup);
            app.MixedButton.Icon = fullfile(pathToMLAPP, 'Icons', 'vegetables.png');
            app.MixedButton.Text = 'Mixed';
            app.RobotActionButtonGroup = uibuttongroup(app.UIFigure);
            app.RobotActionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProduceButtonGroupButtonDown, true);
            app.RobotActionButtonGroup.Enable = 'off';
            app.RobotActionButtonGroup.Title = 'Robot Action';
            app.Button = uitogglebutton(app.RobotActionButtonGroup);
            app.Button.Text = '';
            app.Button.Value = true;
            app.HarvestButton = uitogglebutton(app.RobotActionButtonGroup);
            app.HarvestButton.Icon = fullfile(pathToMLAPP, 'Icons', 'robotics.png');
            app.HarvestButton.Text = 'Harvest';
            app.SortButton = uitogglebutton(app.RobotActionButtonGroup);
            app.SortButton.Icon = fullfile(pathToMLAPP, 'Icons', 'sorting.png');
            app.SortButton.Text = 'Sort';
            app.DualActionButton = uitogglebutton(app.RobotActionButtonGroup);
            app.DualActionButton.Icon = fullfile(pathToMLAPP, 'Icons', 'settings-gears.png');
            app.DualActionButton.Text = 'Dual Action';
            app.ESTOP = uibutton(app.UIFigure, 'state');
            app.ESTOP.ValueChangedFcn = createCallbackFcn(app, @ESTOPValueChanged, true);
            app.ESTOP.Enable = 'off';
            app.ESTOP.Icon = fullfile(pathToMLAPP, 'Icons', 'emergency-button.png');
            app.ESTOP.Text = '';
            app.STARTButton_2 = uibutton(app.UIFigure, 'push');
            app.STARTButton_2.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_2Pushed, true);
            app.STARTButton_2.Text = 'START';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'harvestmateLogo.png');
            app.GripperSliderLabel = uilabel(app.UIFigure);
            app.GripperSliderLabel.Enable = 'off';
            app.GripperSliderLabel.Text = 'Gripper';
            app.GripperSlider = uislider(app.UIFigure);
            app.GripperSlider.Limits = [0 40];
            app.GripperSlider.MajorTicks = [0 10 20 30 40];
            app.GripperSlider.MajorTickLabels = {'Open', '', '', '', 'Close'};
            app.GripperSlider.ValueChangedFcn = createCallbackFcn(app, @GripperSliderValueChanged, true);
            app.GripperSlider.Enable = 'off';
            app.Lamp = uilamp(app.UIFigure);
            app.ManualSwitch = uiswitch(app.UIFigure, 'slider');
            app.ManualSwitch.ValueChangedFcn = createCallbackFcn(app, @ManualSwitchValueChanged, true);
            app.ManualSwitch.Enable = 'off';
            app.ManualModeControlsLabel = uilabel(app.UIFigure);
            app.ManualModeControlsLabel.Text = 'Manual Mode Controls';
            app.RobotSwitch = uiswitch(app.UIFigure, 'rocker');
            app.RobotSwitch.Items = {'Sorter', 'Harvester'};
            app.RobotSwitch.ValueChangedFcn = createCallbackFcn(app, @RobotSwitchValueChanged, true);
            app.RobotSwitch.Enable = 'off';
            app.RobotSwitch.Value = 'Harvester';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Enable = 'off';
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint1Slider.Enable = 'off';
            app.Joint1Slider.Value = 50;
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Enable = 'off';
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint2Slider.Enable = 'off';
            app.Joint2Slider.Value = 50;
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Enable = 'off';
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint3Slider.Enable = 'off';
            app.Joint3Slider.Value = 50;
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Enable = 'off';
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint4Slider.Enable = 'off';
            app.Joint4Slider.Value = 50;
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Enable = 'off';
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            app.Joint5Slider.Enable = 'off';
            app.Joint5Slider.Value = 50;
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Enable = 'off';
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.Joint6Slider.Enable = 'off';
            app.Joint6Slider.Value = 50;
            app.Joint7SliderLabel = uilabel(app.UIFigure);
            app.Joint7SliderLabel.Enable = 'off';
            app.Joint7SliderLabel.Text = 'Joint 7';
            app.Joint7Slider = uislider(app.UIFigure);
            app.Joint7Slider.ValueChangedFcn = createCallbackFcn(app, @Joint7SliderValueChanged, true);
            app.Joint7Slider.Enable = 'off';
            app.Joint7Slider.Value = 50;
            app.ManualModeLampLabel = uilabel(app.UIFigure);
            app.ManualModeLampLabel.Text = 'Manual Mode';
            app.ManualModeLamp = uilamp(app.UIFigure);
            app.ESTOPLabel = uilabel(app.UIFigure);
            app.ESTOPLabel.Enable = 'off';
            app.ESTOPLabel.Text = 'E-STOP';
            app.Reset = uibutton(app.UIFigure, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Icon = fullfile(pathToMLAPP, 'Icons', 'reset.png');
            app.Reset.Enable = 'off';
            app.Reset.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HarvestMate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end